classdef OperacionesBasicas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FactorAtnhButton                matlab.ui.control.Button
        FactorAtnxButton                matlab.ui.control.Button
        FactorAmpxEditField             matlab.ui.control.NumericEditField
        FactordeamplificacinkEditFieldLabel_3  matlab.ui.control.Label
        FactorAmphEditField             matlab.ui.control.NumericEditField
        FactordeamplificacinkEditFieldLabel_2  matlab.ui.control.Label
        FactorAmphButton                matlab.ui.control.Button
        FactorAmpxButton                matlab.ui.control.Button
        SealresultadoLabel              matlab.ui.control.Label
        AtenuacinButton_h               matlab.ui.control.Button
        AmplificacinButton_h            matlab.ui.control.Button
        AtenuacinButton_x               matlab.ui.control.Button
        AmplificacinButton_x            matlab.ui.control.Button
        ConsejoLabel                    matlab.ui.control.Label
        inicialEditField_hn             matlab.ui.control.NumericEditField
        PosicininicialEditField_2Label  matlab.ui.control.Label
        inicialEditField_xn             matlab.ui.control.NumericEditField
        PosicininicialEditFieldLabel_2  matlab.ui.control.Label
        GraficarhnButton                matlab.ui.control.Button
        GraficarxnButton                matlab.ui.control.Button
        Practica1OperacionesbasicasLabel  matlab.ui.control.Label
        DesplazamientoButton_h          matlab.ui.control.Button
        ReflejoButton_h                 matlab.ui.control.Button
        AtenuacinButton_F               matlab.ui.control.Button
        AmplificacinButton_F            matlab.ui.control.Button
        DiezmacinButton                 matlab.ui.control.Button
        InterpolacinButton              matlab.ui.control.Button
        ConvolucinButton                matlab.ui.control.Button
        SumaRestaButton                 matlab.ui.control.Button
        IntroduzcasussealesLabel        matlab.ui.control.Label
        xnEditField                     matlab.ui.control.EditField
        xnEditFieldLabel                matlab.ui.control.Label
        hnEditField                     matlab.ui.control.EditField
        hnEditFieldLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_h                        matlab.ui.control.UIAxes
        UIAxes_x                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xn % Señal x(n)
        inicio_x % Desplazamioento para el punto inicial de x(n)
        
        hn % Señal h(n)
        inicio_h % Desplazamioento para el punto inicial de h(n)
    end
    
    methods (Access = private)
        
        function updateplot( app, x, n )
                   surf(app.UIAxes,peaks(n));
                   colormap(app.UIAxes,winter);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarxnButton
        function GraficarxnButtonPushed(app, event)
             cla(app.UIAxes_x, 'reset');
             vec = str2double(strsplit(app.xnEditField.Value)); 
             inicial = app.inicialEditField_xn.Value;
             if (inicial < length(vec) && inicial >= 0)
                xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             
             stem(app.UIAxes_x, xi, vec,'-r'); 
             
             app.xn = vec;
             app.inicio_x = xi;
        end

        % Button pushed function: GraficarhnButton
        function GraficarhnButtonPushed(app, event)
             cla(app.UIAxes_h, 'reset');
             vec = str2double(strsplit(app.hnEditField.Value)); 
             inicial = app.inicialEditField_hn.Value;
             if (inicial < length(vec) && inicial >= 0)
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             
             stem(app.UIAxes_h, xi, vec,'-b'); 
             
             app.hn = vec;
             app.inicio_h = xi;
        end

        % Button pushed function: AmplificacinButton_x
        function AmplificacinButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor de amplificación (k) =';
            app.FactorAmpxButton.Visible = 'on';
            app.FactorAtnxButton.Visible = 'off';
        end

        % Button pushed function: FactorAmpxButton
        function FactorAmpxButtonPushed(app, event)
            cla(app.UIAxes_x, 'reset');
            k = app.FactorAmpxEditField.Value;
            app.xn = app.xn * k;
            stem(app.UIAxes_x, app.inicio_x, app.xn,'-r'); 
        end

        % Button pushed function: FactorAtnxButton
        function FactorAtnxButtonPushed(app, event)
            cla(app.UIAxes_x, 'reset');
            k = app.FactorAmpxEditField.Value;
            app.xn = app.xn / k;
            stem(app.UIAxes_x, app.inicio_x, app.xn,'-r'); 
        end

        % Button pushed function: AtenuacinButton_x
        function AtenuacinButton_xPushed(app, event)
            app.FactorAmpxEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_3.Text ='Factor de atenuación (k) =';
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAtnxButton.Visible = 'on';
        end

        % Button pushed function: AmplificacinButton_h
        function AmplificacinButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor de amplificación (k) =';
            app.FactorAmphButton.Visible = 'on';
            app.FactorAtnhButton.Visible = 'off';
        end

        % Button pushed function: AtenuacinButton_h
        function AtenuacinButton_hPushed(app, event)
            app.FactorAmphEditField.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'on';
            app.FactordeamplificacinkEditFieldLabel_2.Text ='Factor de atenuación (k) =';
            app.FactorAmphButton.Visible = 'off';
            app.FactorAtnhButton.Visible = 'on';
        end

        % Button pushed function: FactorAmphButton
        function FactorAmphButtonPushed(app, event)
            cla(app.UIAxes_h, 'reset');
            k = app.FactorAmphEditField.Value;
            app.hn = app.hn * k;
            stem(app.UIAxes_h, app.inicio_h, app.hn,'-b'); 
        end

        % Button pushed function: FactorAtnhButton
        function FactorAtnhButtonPushed(app, event)
            cla(app.UIAxes_h, 'reset');
            k = app.FactorAmphEditField.Value;
            app.hn = app.hn / k;
            stem(app.UIAxes_h, app.inicio_h, app.hn,'-r'); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'Señal x(n)')
            xlabel(app.UIAxes_x, 'n')
            ylabel(app.UIAxes_x, 'x(n)')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_h = uiaxes(app.UIFigure);
            title(app.UIAxes_h, 'Señal h(n)')
            xlabel(app.UIAxes_h, 'n')
            ylabel(app.UIAxes_h, 'h(n)')
            zlabel(app.UIAxes_h, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal F(n)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.hnEditFieldLabel = uilabel(app.UIFigure);
            app.hnEditFieldLabel.Text = 'h(n) = ';
            app.hnEditField = uieditfield(app.UIFigure, 'text');
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x(n) = ';
            app.xnEditField = uieditfield(app.UIFigure, 'text');
            app.IntroduzcasussealesLabel = uilabel(app.UIFigure);
            app.IntroduzcasussealesLabel.Text = 'Introduzca sus señales';
            app.SumaRestaButton = uibutton(app.UIFigure, 'push');
            app.SumaRestaButton.Text = 'Suma / Resta';
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.Text = 'Convolución';
            app.InterpolacinButton = uibutton(app.UIFigure, 'push');
            app.InterpolacinButton.Text = 'Interpolación';
            app.DiezmacinButton = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton.Text = 'Diezmación ';
            app.AmplificacinButton_F = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_F.Text = 'Amplificación';
            app.AtenuacinButton_F = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_F.Text = 'Atenuación';
            app.ReflejoButton_h = uibutton(app.UIFigure, 'push');
            app.ReflejoButton_h.Text = 'Reflejo';
            app.DesplazamientoButton_h = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_h.Text = 'Desplazamiento';
            app.Practica1OperacionesbasicasLabel = uilabel(app.UIFigure);
            app.Practica1OperacionesbasicasLabel.Text = 'Practica 1: Operaciones basicas';
            app.GraficarxnButton = uibutton(app.UIFigure, 'push');
            app.GraficarxnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarxnButtonPushed, true);
            app.GraficarxnButton.Text = 'Graficar x(n)';
            app.GraficarhnButton = uibutton(app.UIFigure, 'push');
            app.GraficarhnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarhnButtonPushed, true);
            app.GraficarhnButton.Text = 'Graficar h(n)';
            app.PosicininicialEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PosicininicialEditFieldLabel_2.Text = 'Posición inicial =';
            app.inicialEditField_xn = uieditfield(app.UIFigure, 'numeric');
            app.PosicininicialEditField_2Label = uilabel(app.UIFigure);
            app.PosicininicialEditField_2Label.Text = 'Posición inicial =';
            app.inicialEditField_hn = uieditfield(app.UIFigure, 'numeric');
            app.ConsejoLabel = uilabel(app.UIFigure);
            app.ConsejoLabel.Text = '*Las posiciones iniciales empiezan contando al primer elemento como 0';
            app.AmplificacinButton_x = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_xPushed, true);
            app.AmplificacinButton_x.Text = 'Amplificación';
            app.AtenuacinButton_x = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_x.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_xPushed, true);
            app.AtenuacinButton_x.Text = 'Atenuación';
            app.AmplificacinButton_h = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_hPushed, true);
            app.AmplificacinButton_h.Text = 'Amplificación';
            app.AtenuacinButton_h = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_h.ButtonPushedFcn = createCallbackFcn(app, @AtenuacinButton_hPushed, true);
            app.AtenuacinButton_h.Text = 'Atenuación';
            app.SealresultadoLabel = uilabel(app.UIFigure);
            app.SealresultadoLabel.Text = 'Señal resultado';
            app.FactorAmpxButton = uibutton(app.UIFigure, 'push');
            app.FactorAmpxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAmpxButtonPushed, true);
            app.FactorAmpxButton.Visible = 'off';
            app.FactorAmpxButton.Text = 'Amplificar x(n)';
            app.FactorAmphButton = uibutton(app.UIFigure, 'push');
            app.FactorAmphButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAmphButtonPushed, true);
            app.FactorAmphButton.Visible = 'off';
            app.FactorAmphButton.Text = 'Amplificar h(n)';
            app.FactordeamplificacinkEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FactordeamplificacinkEditFieldLabel_2.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_2.Text = 'Factor de amplificación (k) =';
            app.FactorAmphEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorAmphEditField.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3 = uilabel(app.UIFigure);
            app.FactordeamplificacinkEditFieldLabel_3.Visible = 'off';
            app.FactordeamplificacinkEditFieldLabel_3.Text = 'Factor de amplificación (k) =';
            app.FactorAmpxEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactorAmpxEditField.Visible = 'off';
            app.FactorAtnxButton = uibutton(app.UIFigure, 'push');
            app.FactorAtnxButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAtnxButtonPushed, true);
            app.FactorAtnxButton.Visible = 'off';
            app.FactorAtnxButton.Text = 'Atenuar x(n)';
            app.FactorAtnhButton = uibutton(app.UIFigure, 'push');
            app.FactorAtnhButton.ButtonPushedFcn = createCallbackFcn(app, @FactorAtnhButtonPushed, true);
            app.FactorAtnhButton.Visible = 'off';
            app.FactorAtnhButton.Text = 'Atenuar x(n)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperacionesBasicas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end